classdef c13e28 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        StopButton  matlab.ui.control.Button
        GoButton    matlab.ui.control.Button
        LampG       matlab.ui.control.Lamp
        LampGLabel  matlab.ui.control.Label
        LampY       matlab.ui.control.Lamp
        LampYLabel  matlab.ui.control.Label
        LampR       matlab.ui.control.Lamp
        LampRLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.LampR.Color = [.3 0 0];
            app.LampY.Color = [.3 .3 0];
            app.LampG.Color = [0 1 0];
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.LampR.Color = [.3 0 0];
            app.LampY.Color = [1 1 0];
            app.LampG.Color = [0 .3 0];
            pause(1);
            app.LampR.Color = [1 0 0];
            app.LampY.Color = [.3 .3 0];
            app.LampG.Color = [0 .3 0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LampRLabel = uilabel(app.UIFigure);
            app.LampRLabel.Text = 'LampR';
            app.LampR = uilamp(app.UIFigure);
            app.LampYLabel = uilabel(app.UIFigure);
            app.LampYLabel.Text = 'LampY';
            app.LampY = uilamp(app.UIFigure);
            app.LampGLabel = uilabel(app.UIFigure);
            app.LampGLabel.Text = 'LampG';
            app.LampG = uilamp(app.UIFigure);
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = c13e28

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end